classdef denglu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        denglu1          matlab.ui.Figure
        dengl            matlab.ui.control.Button
        chengxushuoming  matlab.ui.control.Button
        QuitButton       matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: dengl
        function denglPushed(app, event)

%% 新建图形并设置相关属性
%scrsz = get(0,'ScreenSize');
Hd_login = figure('NumberTitle', 'off', 'Resize','off','Color',[0.753,0.753,0.753],'Name', 'Log in','tag','Hd_login');
%取得Figure的JavaFrame。
%修改图标
newIcon = javax.swing.ImageIcon('denglujiemian.jpg');
figFrame = get(Hd_login,'JavaFrame');            
figFrame.setFigureIcon(newIcon);
%转换图形窗口菜单条的“开”与“关”5
set(Hd_login,'menubar','none','Unit','normalized','Position',[0.28,0.32,0.38,0.45]);
%% 设置登录窗口背景图片
%% 添加背景图片
Hd_axes = axes('units','normalized','position',[0 0 1 1],'tag','Hd_axes');
uistack(Hd_axes,'down')
II=imread('denglujiemian.jpg');
image(II)
% colormap gray
set(Hd_axes,'handlevisibility','off','visible','off');
%% 添加登录所需控件：用户名框、密码框等
%用户名
Hd_user = uicontrol('Parent',Hd_login,'Style','text',...
    'String','UserName',...
    'Units','normalized',...
    'FontWeight','bold',...    
    'Background',[0.8 0.8 0.8],...
    'ForegroundColor','black',...
    'Units','normalized',...
    'FontSize',20,...
    'Enable','inactive',...
    'tag','Hd_user',...
    'Position',[0.1,0.68,0.25,0.15],...
    'CallBack','');
Hd_unserin = uicontrol('Parent',Hd_login,'Style','Edit',...
    'String','',...
    'FontWeight','bold',...
    'ForegroundColor',[0.871,0.49,0],...
    'Units','normalized',...
    'FontSize',10,...
    'HorizontalAlignment','left',...
    'tag','Hd_unserin',...
    'Position',[0.38,0.68,0.55,0.15],...
    'CallBack','');
%密码
Hd_passwd = uicontrol('Parent',Hd_login,'Style','text',...
    'String','password',...
    'Units','normalized',...
    'FontWeight','bold',...    
    'Background',[0.8 0.8 0.8],...
    'ForegroundColor','black',...
    'Units','normalized',...
    'FontSize',20,...
    'Enable','inactive',...
    'tag','Hd_passwd',...
    'Position',[0.1,0.48,0.25,0.15],...
    'CallBack','');
%java 实现密码文本框输入
jPass = javax.swing.JPasswordField;
J_passwdin = javacomponent(jPass,[198 167 287 52]);
%提示 文本框
hTip = uicontrol('style','text','visible','off','String','',...
    'FontSize',18,...
    'FontWeight','bold',...
    'ForegroundColor','red',...
    'position',[150 300 250 30]);
%登录，修改密码
uicontrol('pos',[100 80 140 40],'string','Yes','FontWeight','bold',...
    'ForegroundColor',[0 0 1],...
    'FontSize',18,...
    'callback',{@loginCheck,Hd_unserin,J_passwdin,hTip});
uicontrol('pos',[290 80 140 40],'string','change the password','FontWeight','bold',...
    'ForegroundColor',[0 0 1],...
    'FontSize',10,...
    'callback',{@modifyPasswd,hTip});
 end
function modifyPasswd(~,~,hTip)
    set(hTip,'Visible','on','string','contact developer');
    pause(3);
    set(hTip,'Visible','off');           
        end

        % Button pushed function: chengxushuoming
        function chengxushuomingButtonPushed(app, event)
 chengxushuoming
        end

        % Callback function
        function denglu1CloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
             delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.denglu1 = uifigure;
            app.denglu1.Name = '初始界面（the initial figure）';
            app.dengl = uibutton(app.denglu1, 'push');
            app.dengl.ButtonPushedFcn = createCallbackFcn(app, @denglPushed, true);
            app.dengl.Text = 'Log in';
            app.chengxushuoming = uibutton(app.denglu1, 'push');
            app.chengxushuoming.ButtonPushedFcn = createCallbackFcn(app, @chengxushuomingButtonPushed, true);
            app.chengxushuoming.Text = 'The description about this program';
            app.QuitButton = uibutton(app.denglu1, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = denglu

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.denglu1)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.denglu1)
        end
    end
end