classdef mopi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChooseUIFigure  matlab.ui.Figure
        value           matlab.ui.control.Button
        ClickitLabel    matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: value
        function valuePushed(app, event)
  [filename,pathname]=uigetfile('.jpg,.png','choose the picture you want to buff')    
%  value=menu('which figure you want to deal with?',1,2,3,4,5)
% switch value
%     case 1
       I= double(imread(filename));
H = RF(I,30,100) - I + 128;
G = imfilter(H,fspecial('gaussian',[3 3],100));
opacity = 50;
Dst = (I*(100 - opacity)+(I+2*G-256)*opacity)/100;
imshow([uint8(I) uint8(Dst)]);  
%     case 2
%         I= double(imread('2.jpg'));
% H = RF(I,30,100) - I + 128;
% G = imfilter(H,fspecial('gaussian',[3 3],100));
% opacity = 50;
% Dst = (I*(100 - opacity)+(I+2*G-256)*opacity)/100;
% imshow([uint8(I) uint8(Dst)]);  
%     case 3
%        I= double(imread('3.jpg'));
% H = RF(I,30,100) - I + 128;
% G = imfilter(H,fspecial('gaussian',[3 3],100));
% opacity = 50;
% Dst = (I*(100 - opacity)+(I+2*G-256)*opacity)/100;
% imshow([uint8(I) uint8(Dst)]);  
%     case 4
%          I= double(imread('4.jpg'));
% H = RF(I,30,100) - I + 128;
% G = imfilter(H,fspecial('gaussian',[3 3],100));
% opacity = 50;
% Dst = (I*(100 - opacity)+(I+2*G-256)*opacity)/100;
% imshow([uint8(I) uint8(Dst)]);  
%     case 5
%                 I= double(imread('5.png'));
% H = RF(I,30,100) - I + 128;
% G = imfilter(H,fspecial('gaussian',[3 3],100));
% opacity = 50;
% Dst = (I*(100 - opacity)+(I+2*G-256)*opacity)/100;
% imshow([uint8(I) uint8(Dst)]);  
%         
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChooseUIFigure = uifigure;
            app.ChooseUIFigure.Name = 'Choose';
            app.value = uibutton(app.ChooseUIFigure, 'push');
            app.value.ButtonPushedFcn = createCallbackFcn(app, @valuePushed, true);
            app.value.Text = 'Choose the pictures you want to deal with';
            app.ClickitLabel = uilabel(app.ChooseUIFigure);
            app.ClickitLabel.Text = 'Click it!';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mopi

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChooseUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChooseUIFigure)
        end
    end
end