classdef shuiyinyunxing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        RunButton  matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
[filename,pathname]=uigetfile('.jpg','choose the picture you want to remove the watermark')
imread(filename)
% imshow(filename,'Parent',app.UIAxes);
% filename = '1.png';
image=imread(filename);
for i=1:size(image,1)
for j=1:size(image,2)
if image(i,j,1)==image(i,j,2)&&image(i,j,2)==image(i,j,3)
for k=1:3
if image(i,j,k)<=255&&image(i,j,k)>=200%在这里替换水印最浅色的RGB值 在这里替换水印最深色的RGB
image(i,j,k)=255;
end
end
end
end
end
imshow(filename)
imwrite(image, strcat('Corrected',filename));
imread(strcat('Corrected',filename))
pause(5)
imshow(strcat('Corrected',filename))


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
        end
    end

    methods (Access = public)

        % Construct app
        function app = shuiyinyunxing

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end