classdef tu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        UIAxes     matlab.ui.control.UIAxes
        UIAxes2    matlab.ui.control.UIAxes
        RunButton  matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

for page = 1
imread('1.png')
imshow('1.png','Parent',app.UIAxes);
filename = '1.png';
image=imread(filename);
for i=1:size(image,1)
for j=1:size(image,2)
if image(i,j,1)==image(i,j,2)&&image(i,j,2)==image(i,j,3)
for k=1:3
if image(i,j,k)<=255&&image(i,j,k)>=200%在这里替换水印最浅色的RGB值 在这里替换水印最深色的RGB
image(i,j,k)=255;
end
end
end
end
end

imwrite(image, strcat('finished',filename));
imread('finished1.png')
imshow('finished1.png','Parent',app.UIAxes2)

end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Corrected image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
        end
    end

    methods (Access = public)

        % Construct app
        function app = tu

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end