classdef wenzitiqu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        RunButton            matlab.ui.control.Button
        HelpButton           matlab.ui.control.Button
        TextExtractionLabel  matlab.ui.control.Label
        TextTextAreaLabel    matlab.ui.control.Label
        TextTextArea         matlab.ui.control.TextArea
        UIAxes               matlab.ui.control.UIAxes
        IntroductionButton   matlab.ui.control.Button
    end

    methods (Access = private)

        % Callback function: RunButton, TextTextArea
        function ButtonPushed(app, event)
%            value=menu('which figure you want to deal with?',1,2,3,4);
%      global ocrobj text
%      switch value          
%     case 1
 [filename,pathname]=uigetfile('.jpg,.png','choose the picture you want to deal with')
        imread(filename)
        imshow(filename,'Parent',app.UIAxes);
        ocrobj = ocr(imread(filename));
        
%     case 2
%           imread('Pic2.png')
%         imshow('Pic2.png','Parent',app.UIAxes);
%         ocrobj = ocr(imread('Pic2.png'));
%      
%     case 3
%         imread('Pic3.png')
%         imshow('Pic3.png','Parent',app.UIAxes);
%         ocrobj = ocr(imread('Pic3.png'));
%       
%     case 4
%         imread('Pic4.png')
%         imshow('Pic4.png','Parent',app.UIAxes);
%         ocrobj = ocr(imread('Pic4.png'));
%      end
             text=ocrobj.Text(1,:);
             app.TextTextArea.Value=text(1,:);

        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            delete(app)         
            run bangzhu
        end

        % Button pushed function: IntroductionButton
        function IntroductionButtonPushed(app, event)
run introduction
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = '文字提取';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.TextExtractionLabel = uilabel(app.UIFigure);
            app.TextExtractionLabel.Text = 'Text Extraction';
            app.TextTextAreaLabel = uilabel(app.UIFigure);
            app.TextTextAreaLabel.Text = 'Text';
            app.TextTextArea = uitextarea(app.UIFigure);
            app.TextTextArea.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.IntroductionButton = uibutton(app.UIFigure, 'push');
            app.IntroductionButton.ButtonPushedFcn = createCallbackFcn(app, @IntroductionButtonPushed, true);
            app.IntroductionButton.Text = 'Introduction ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = wenzitiqu

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end